% description: Copyright 2019 Luca Della Santina
classdef FollicleFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        TabGroup             matlab.ui.container.TabGroup
        ManualDetectionTab   matlab.ui.container.Tab
        btnChooseUser        matlab.ui.control.Button
        btnPhotosFolder      matlab.ui.control.Button
        btnTagFollicles      matlab.ui.control.Button
        btnTraceEyelid       matlab.ui.control.Button
        btnDeleteRow         matlab.ui.control.Button
        btnRefreshTable      matlab.ui.control.Button
        SettingsTab          matlab.ui.container.Tab
        DefaultZoomPanel     matlab.ui.container.Panel
        FolliclesLabel       matlab.ui.control.Label
        cmbZoomFollicles     matlab.ui.control.Spinner
        EyelidLabel          matlab.ui.control.Label
        cmbZoomEyelid        matlab.ui.control.Spinner
        DefaultColorsPanel   matlab.ui.container.Panel
        btnColorSelected     matlab.ui.control.Button
        btnColorFalse        matlab.ui.control.Button
        btnColorTrue         matlab.ui.control.Button
        btnColorMaybe        matlab.ui.control.Button
        ColorFalse           matlab.ui.control.Lamp
        ColorSelected        matlab.ui.control.Lamp
        ColorTrue            matlab.ui.control.Lamp
        ColorMaybe           matlab.ui.control.Lamp
        AboutTab             matlab.ui.container.Tab
        Image                matlab.ui.control.Image
        FollicleFinderLabel  matlab.ui.control.Label
        Version121Label      matlab.ui.control.Label
        Copyright2019LucaDellaSantinaLabel  matlab.ui.control.Label
        Panel                matlab.ui.container.Panel
        Log                  matlab.ui.control.EditField
        Lamp                 matlab.ui.control.Lamp
        UITable              matlab.ui.control.Table
    end

    
    properties (Access = private)
        UserName        = '';
        ImagesFolder    = '';
        RowSelected     = []; % Row selected in UITable
        FollicleTypes   = containers.Map({'False','Maybe','True'},[-1,0,1]);
        DiagnosisTypes  = containers.Map({'Normal','TF','TI','TT','TS','CO'}, [1,2,3,4,5,6]);
    end
    
    methods (Access = private)
        
        function RefreshUserName(app)
            if ~isempty(app.UserName)
                app.UIFigure.Name = ['Follicle Finder - User: ' app.UserName];
            else
                app.UIFigure.Name = 'Follicle Finder';
            end
        end
        
        function RefreshTrainingTable(app)
            % Refreshes the list of available traning session
            app.UITable.Data = {};
            app.UITable.Data = listTraining;

            if isempty(app.UITable.Data)
                app.btnDeleteRow.Enable = 'off';                
            else
                app.btnDeleteRow.Enable = 'off';                
            end
            
            if isempty(app.ImagesFolder)
                app.btnTagFollicles.Enable  = 'off';
                app.btnTraceEyelid.Enable   = 'off';
            else
                app.btnTagFollicles.Enable  = 'on';
                app.btnTraceEyelid.Enable   = 'on';
            end
        end
        
        function Prefs = getPrefsFollicles(app)
            Prefs                   = struct;
            Prefs.Zoom              = app.cmbZoomFollicles.Value;
            Prefs.actionType        = 'Add';
            Prefs.ColorMaybe        = [100,100,0]; % Pixel beloning to an unselected maybe dot
            Prefs.ColorFalse        = [100,0,0]; % Pixel beloning to an unselected false dot
            Prefs.ColorValid        = [0,100,0]; % Pixel beloning to an unselected valid dot
            Prefs.ColorSelected     = [100,0,100]; % Pixel belonging to a selected dot
        end
        function Prefs = getPrefsEyelid(app)
            Prefs                   = struct;
            Prefs.Zoom              = app.cmbZoomEyelid.Value;
            Prefs.actionType        = 'Enclose';
            Prefs.ColorMaybe        = [100,100,0]; % Pixel beloning to an unselected maybe dot
            Prefs.ColorFalse        = [100,0,0]; % Pixel beloning to an unselected false dot
            Prefs.ColorValid        = [0,100,0]; % Pixel beloning to an unselected valid dot
            Prefs.ColorSelected     = [100,0,100]; % Pixel belonging to a selected dot
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,'center');
            app.RefreshUserName;
            app.RefreshTrainingTable;
        end

        % Button pushed function: btnChooseUser
        function btnChooseUserButtonPushed(app, event)
            answer = inputdlg('User name:', 'Select user');
            
            if ~isempty(answer)
                app.UserName = answer{:};
                app.RefreshUserName;
                app.btnPhotosFolder.Enable = 'on';
            else
                app.btnPhotosFolder.Enable = 'off';
                app.btnTagFollicles.Enable  = 'off';
            end
        end

        % Button pushed function: btnPhotosFolder
        function btnPhotosFolderButtonPushed(app, event)
            selpath = uigetdir(pwd, 'Select folder with photos to score');
            if exist(selpath,"dir")
                app.ImagesFolder = selpath;
                app.Log.Value = app.ImagesFolder;
                app.btnTagFollicles.Enable = 'on';
                app.btnTraceEyelid.Enable  = 'on';
            else
                app.ImagesFolder = '';
                app.btnTagFollicles.Enable = 'off';
                app.btnTraceEyelid.Enable  = 'off';
            end
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: btnTagFollicles
        function btnTagFolliclesButtonPushed(app, event)
            app.Log.Value = 'Manually identifying follicles in the training set...';
            app.Lamp.Color = 'Yellow'; pause(0.2);
            
            % Identify image files to score and store path to them in a list
            files = dir(app.ImagesFolder);  % List the content of ImageFolder
            files = files(~[files.isdir]);  % Keep only files (ignore subdirs)
                        
            lstImages = {};
            for i = 1:numel(files)
                currFile = [files(i).folder filesep files(i).name];
                if isempty(lstImages)
                    lstImages = {currFile};
                else
                    lstImages{end+1} = currFile; %#ok
                end                
            end

            % Create a new manual training session
            Training                = struct;
            Training.UID            = generateUID;
            Training.Type           = 'Manual';
            Training.Date           = datetime;
            Training.User           = app.UserName;
            Training.ImagesFolder   = app.ImagesFolder;
            Training.ImagesList     = lstImages;
            Training.ImagesTODOFollicles = lstImages(randperm(numel(lstImages))); % randomize order of images
            Training.ImagesTODOEyelid    = lstImages(randperm(numel(lstImages))); % randomize order of images
            Training.Follicles      = [];
            Training.Eyelid         = [];
            
            % If user selected a training, ask if to edit or start anew
            TODOmode = 'New';
            if ~isempty(app.RowSelected)
                switch questdlg('Existing training selected: Complete unfinished, review analysed images or start anew?','Training selection', 'Complete', 'Review', 'New', 'Complete')
                    case 'Complete'
                        % Load existing training session
                        UID = app.UITable.Data{app.RowSelected(:,1),6}; % Retrieve UID                        
                        Training = loadTraining(UID{:});
                        TODOmode = 'Complete';
                    case 'Review'
                        % Load existing training session
                        UID = app.UITable.Data{app.RowSelected(:,1),6}; % Retrieve UID                        
                        Training = loadTraining(UID{:});
                        TODOmode = 'Review';
                end    
            end                      
            
            switch TODOmode
                case {'New', 'Complete'}
                    TODOlist = Training.ImagesTODOFollicles;
                case 'Review'
                    TODOlist = setdiff(Training.ImagesList, Training.ImagesTODOFollicles);
            end
            
            % Propose one by one each image to the user to score
            for i = 1:numel(TODOlist)
                ImgFileName = TODOlist{i};
                
                try
                    Img = imread(ImgFileName);
                catch
                    disp(['Cannot read this image file: ' ImgFileName]);
                    continue;
                end
                
                switch TODOmode
                    case {'New', 'Complete'}
                        % Crunch to all remaining images
                        Follicles.ImgFile   = ImgFileName;
                        Follicles.ImgSize   = [size(Img, 1), size(Img, 2)]; % Image size (pixels)
                        Follicles.ImgRes    = [1 ,1]; % Resolution (mm/pixels)
                        Follicles.Pos       = [];     % Position of identified objects
                        Follicles.Vox       = [];     % Pixels belonging to identified objects
                        Follicles.Filter    = [];     % 1 = valid, 0 = invalid
                        Follicles.Shape     = struct; % Shape properties (perimeter, length, roundness)
                        Follicles.Diagnosis = 'Normal';
                        
                        
                        % Display custom UI to segment objects of interest
                        F = inspectPhoto(Img, Follicles, app.getPrefsFollicles);
                        
                        if ~isempty(F)
                            % Save the new manual training to disk
                            if isempty(Training.Follicles)
                                Training.Follicles = F;
                            else
                                Training.Follicles(end+1) = F;
                            end
                            Training.ImagesTODOFollicles = setdiff(Training.ImagesTODOFollicles, ImgFileName);
                            saveTraining(Training);
                        else
                            break % User cancelled manual traning session
                        end
                    case {'Review'}
                        % Find objects belonging to current image
                        Found = 0;
                        for j = 1:numel(Training.Follicles)
                            if strcmp(Training.Follicles(j).ImgFile, ImgFileName)
                                Follicles = Training.Follicles(j);
                                Found = j;
                                break
                            end
                        end                                  
                        if Found == 0
                            disp(['Cannot find the follicles detected for ' ImgFileName]);
                            break
                        end

                        % Display custom UI to segment objects of interest
                        F = inspectPhoto(Img, Follicles, app.getPrefsFollicles);
                       
                        if ~isempty(F)
                            % Save the new manual training to disk
                            Training.Follicles(Found) = F;
                            saveTraining(Training);
                        else
                            break % User cancelled manual traning session
                        end
                end
            end

            app.RefreshTrainingTable;            
            app.Log.Value = 'Manually identifying follicles in the training set...DONE';
            app.Lamp.Color = 'Green';
        end

        % Button pushed function: btnTraceEyelid
        function btnTraceEyelidButtonPushed(app, event)
            app.Log.Value = 'Manually identifying eyelid in the training set...';
            app.Lamp.Color = 'Yellow'; pause(0.2);
            
            % Identify image files to score and store path to them in a list
            files = dir(app.ImagesFolder);  % List the content of ImageFolder
            files = files(~[files.isdir]);  % Keep only files (ignore subdirs)
                        
            lstImages = {};
            for i = 1:numel(files)
                currFile = [files(i).folder filesep files(i).name];
                if isempty(lstImages)
                    lstImages = {currFile};
                else
                    lstImages{end+1} = currFile; %#ok
                end                
            end

            % Create a new manual training session
            Training                = struct;
            Training.UID            = generateUID;
            Training.Type           = 'Manual';
            Training.Date           = datetime;
            Training.User           = app.UserName;
            Training.ImagesFolder   = app.ImagesFolder;
            Training.ImagesList     = lstImages;
            Training.ImagesTODOFollicles = lstImages(randperm(numel(lstImages))); % randomize order of images
            Training.ImagesTODOEyelid    = lstImages(randperm(numel(lstImages))); % randomize order of images
            Training.Follicles      = [];
            Training.Eyelid         = [];
            
            % If user selected a training, ask if to edit or start anew
            TODOmode = 'New';
            if ~isempty(app.RowSelected)
                switch questdlg('Existing training selected: Complete unfinished, review analysed images or start anew?','Training selection', 'Complete', 'Review', 'New', 'Complete')
                    case 'Complete'
                        % Load existing training session
                        UID = app.UITable.Data{app.RowSelected(:,1),6}; % Retrieve UID                        
                        Training = loadTraining(UID{:});
                        TODOmode = 'Complete';
                    case 'Review'
                        % Load existing training session
                        UID = app.UITable.Data{app.RowSelected(:,1),6}; % Retrieve UID                        
                        Training = loadTraining(UID{:});
                        TODOmode = 'Review';
                end    
            end                      
            
            switch TODOmode
                case {'New', 'Complete'}
                    TODOlist = Training.ImagesTODOEyelid;
                case 'Review'
                    TODOlist = setdiff(Training.ImagesList, Training.ImagesTODOEyelid);
            end
            
            % Propose one by one each image to the user to score
            for i = 1:numel(TODOlist)
                ImgFileName = TODOlist{i};
                
                try
                    Img = imread(ImgFileName);
                catch
                    disp(['Cannot read this image file: ' ImgFileName]);
                    continue;
                end
                
                switch TODOmode
                    case {'New', 'Complete'}
                        % Crunch to all remaining images
                        Eyelid.ImgFile   = ImgFileName;
                        Eyelid.ImgSize   = [size(Img, 1), size(Img, 2)]; % Image size (pixels)
                        Eyelid.ImgRes    = [1 ,1]; % Resolution (mm/pixels)
                        Eyelid.Pos       = [];     % Position of identified objects
                        Eyelid.Vox       = [];     % Pixels belonging to identified objects
                        Eyelid.Filter    = [];     % 1 = valid, 0 = invalid
                        Eyelid.Shape     = struct; % Shape properties (perimeter, length, roundness)
                        Eyelid.Diagnosis = 'Normal';

                        
                        % Display custom UI to segment objects of interest
                        F = inspectPhoto(Img, Eyelid, app.getPrefsEyelid);
                        
                        if ~isempty(F)
                            % Save the new manual training to disk
                            if isempty(Training.Eyelid)
                                Training.Eyelid = F;
                            else
                                Training.Eyelid(end+1) = F;
                            end
                            Training.ImagesTODOEyelid = setdiff(Training.ImagesTODOEyelid, ImgFileName);
                            saveTraining(Training);
                        else
                            break % User cancelled manual traning session
                        end
                    case {'Review'}
                        % Find objects belonging to current image
                        Found = 0;
                        for j = 1:numel(Training.Eyelid)
                            if strcmp(Training.Eyelid(j).ImgFile, ImgFileName)
                                Eyelid = Training.Eyelid(j);
                                Found = j;
                                break
                            end
                        end                                  
                        if Found == 0
                            disp(['Cannot find the eyelid detected for ' ImgFileName]);
                            break
                        end

                        % Display custom UI to segment objects of interest
                        F = inspectPhoto(Img, Eyelid, app.getPrefsEyelid);
                        
                        if ~isempty(F)
                            % Save the new manual training to disk
                            Training.Eyelid(Found) = F;
                            saveTraining(Training);
                        else
                            break % User cancelled manual traning session
                        end
                end
            end

            app.RefreshTrainingTable;            
            app.Log.Value = 'Manually identifying eyelid in the training set...DONE';
            app.Lamp.Color = 'Green';
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            % Store the row selected by user
            app.RowSelected = event.Indices;
            if ~isempty(app.RowSelected)
                app.btnDeleteRow.Enable     = 'on';
                app.btnTagFollicles.Enable  = 'on';
                app.btnTraceEyelid.Enable   = 'on';
            end
        end

        % Button pushed function: btnDeleteRow
        function btnDeleteRowButtonPushed(app, event)
            if ~isempty(app.RowSelected)
                switch questdlg('Do you really want to permanently remove selected training session?','Delete training', 'No', 'Yes', 'No')
                    case 'Yes'
                    app.Log.Value = 'Deleting item from disk...';
                    app.Lamp.Color = 'Yellow'; pause(0.2);
                    
                    UID = app.UITable.Data{app.RowSelected(:,1),6}; % Retrieve UID
                    deleteTraining(UID{:}); % Delete saved training file matching UID
                    app.RowSelected = [];
                    app.RefreshTrainingTable;

                    app.Log.Value = 'Deleting item from disk...DONE';
                    app.Lamp.Color = 'Green'; pause(0.2);
                    
                end
            end            
        end

        % Button pushed function: btnRefreshTable
        function btnRefreshTableButtonPushed(app, event)
            app.Log.Value = 'Refreshing table...';
            app.Lamp.Color = 'Yellow'; pause(0.2);
            
            app.RefreshTrainingTable;
            
            app.Log.Value = 'Refreshing table...DONE';
            app.Lamp.Color = 'Green'; pause(0.2);
            
        end

        % Button pushed function: btnColorSelected
        function btnColorSelectedButtonPushed(app, event)
%             cc = javax.swing.JColorChooser;
%             [jColorChooser,container] = javacomponent(cc,[1,1,450,325],gcf);
%             jColorChooser.getColor
%             java.awt.Color[r=102,g=153,b=255]
        
%             c = uisetcolor([1 0 1],'Choose a color');
%             app.ColorSelected.Color = c;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Follicle Finder';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ManualDetectionTab = uitab(app.TabGroup);
            app.ManualDetectionTab.Title = 'Manual Detection';
            app.btnChooseUser = uibutton(app.ManualDetectionTab, 'push');
            app.btnChooseUser.ButtonPushedFcn = createCallbackFcn(app, @btnChooseUserButtonPushed, true);
            app.btnChooseUser.Icon = '48px-split.png';
            app.btnChooseUser.Tooltip = {'Change current user name'};
            app.btnChooseUser.Text = 'Choose user';
            app.btnPhotosFolder = uibutton(app.ManualDetectionTab, 'push');
            app.btnPhotosFolder.ButtonPushedFcn = createCallbackFcn(app, @btnPhotosFolderButtonPushed, true);
            app.btnPhotosFolder.Icon = '48px-Training-set.png';
            app.btnPhotosFolder.Enable = 'off';
            app.btnPhotosFolder.Tooltip = {'Open a folder containing photos to score'};
            app.btnPhotosFolder.Text = 'Photos folder';
            app.btnTagFollicles = uibutton(app.ManualDetectionTab, 'push');
            app.btnTagFollicles.ButtonPushedFcn = createCallbackFcn(app, @btnTagFolliclesButtonPushed, true);
            app.btnTagFollicles.Icon = 'EyelidFollicles.png';
            app.btnTagFollicles.Enable = 'off';
            app.btnTagFollicles.Tooltip = {'Manually identify follicles'};
            app.btnTagFollicles.Text = 'Paint Follicles';
            app.btnTraceEyelid = uibutton(app.ManualDetectionTab, 'push');
            app.btnTraceEyelid.ButtonPushedFcn = createCallbackFcn(app, @btnTraceEyelidButtonPushed, true);
            app.btnTraceEyelid.Icon = 'Eyelid.png';
            app.btnTraceEyelid.Enable = 'off';
            app.btnTraceEyelid.Tooltip = {'Manually identify the eyelid region'};
            app.btnTraceEyelid.Text = 'Trace Eyelid';
            app.btnDeleteRow = uibutton(app.ManualDetectionTab, 'push');
            app.btnDeleteRow.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteRowButtonPushed, true);
            app.btnDeleteRow.Icon = '48px-Delete.png';
            app.btnDeleteRow.Enable = 'off';
            app.btnDeleteRow.Tooltip = {'Remove selected row'};
            app.btnDeleteRow.Text = 'Delete';
            app.btnRefreshTable = uibutton(app.ManualDetectionTab, 'push');
            app.btnRefreshTable.ButtonPushedFcn = createCallbackFcn(app, @btnRefreshTableButtonPushed, true);
            app.btnRefreshTable.Icon = '48px-Refresh.png';
            app.btnRefreshTable.Tooltip = {'Refresh table of analyzed data'};
            app.btnRefreshTable.Text = 'Refresh Table';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.DefaultZoomPanel = uipanel(app.SettingsTab);
            app.DefaultZoomPanel.Title = 'Default Zoom';
            app.FolliclesLabel = uilabel(app.DefaultZoomPanel);
            app.FolliclesLabel.Text = 'Follicles';
            app.cmbZoomFollicles = uispinner(app.DefaultZoomPanel);
            app.cmbZoomFollicles.Limits = [1 32];
            app.cmbZoomFollicles.Value = 2;
            app.EyelidLabel = uilabel(app.DefaultZoomPanel);
            app.EyelidLabel.Text = 'Eyelid';
            app.cmbZoomEyelid = uispinner(app.DefaultZoomPanel);
            app.cmbZoomEyelid.Limits = [1 32];
            app.cmbZoomEyelid.Value = 1;
            app.DefaultColorsPanel = uipanel(app.SettingsTab);
            app.DefaultColorsPanel.Title = 'Default Colors';
            app.btnColorSelected = uibutton(app.DefaultColorsPanel, 'push');
            app.btnColorSelected.ButtonPushedFcn = createCallbackFcn(app, @btnColorSelectedButtonPushed, true);
            app.btnColorSelected.Enable = 'off';
            app.btnColorSelected.Text = 'Selected';
            app.btnColorFalse = uibutton(app.DefaultColorsPanel, 'push');
            app.btnColorFalse.Enable = 'off';
            app.btnColorFalse.Text = 'False';
            app.btnColorTrue = uibutton(app.DefaultColorsPanel, 'push');
            app.btnColorTrue.Enable = 'off';
            app.btnColorTrue.Text = 'True';
            app.btnColorMaybe = uibutton(app.DefaultColorsPanel, 'push');
            app.btnColorMaybe.Enable = 'off';
            app.btnColorMaybe.Text = 'Maybe';
            app.ColorFalse = uilamp(app.DefaultColorsPanel);
            app.ColorSelected = uilamp(app.DefaultColorsPanel);
            app.ColorTrue = uilamp(app.DefaultColorsPanel);
            app.ColorMaybe = uilamp(app.DefaultColorsPanel);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = 'app-icon2.svg';
            app.FollicleFinderLabel = uilabel(app.AboutTab);
            app.FollicleFinderLabel.Text = 'Follicle Finder';
            app.Version121Label = uilabel(app.AboutTab);
            app.Version121Label.Text = 'Version 1.2.1';
            app.Copyright2019LucaDellaSantinaLabel = uilabel(app.AboutTab);
            app.Copyright2019LucaDellaSantinaLabel.Text = 'Copyright 2019 Luca Della Santina';
            app.Panel = uipanel(app.GridLayout);
            app.Log = uieditfield(app.Panel, 'text');
            app.Lamp = uilamp(app.Panel);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'User'; 'Images Folder'; 'Task'; 'Last edit'; 'Analyzed'; 'Unique ID'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = false;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FollicleFinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end